classdef SPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        btnStartStop     matlab.ui.control.Button
        ddAxis           matlab.ui.control.DropDown
        AxisLabel        matlab.ui.control.Label
        ddAvailableComs  matlab.ui.control.DropDown
        PortLabel        matlab.ui.control.Label
        RightPanel       matlab.ui.container.Panel
        aAccelerometer   matlab.ui.control.UIAxes
        aPosition        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        tPlotUpdater % This timer updates the plot view
        mData = zeros(1, 0) % Contains the information from the sensor
    end
    
    methods (Access = public)       
        function tPlotUpdaterCallback(app, obj, event, string_arg)
            instrreset;
            
            maxPoints = 40;            
            device = serial(app.ddAvailableComs.Value);           
            fopen(device);
            
            mTemp = fread(device, 10, 'float32');
            app.mData(end + 1) = mTemp(3);
            
            if length(app.mData) > maxPoints
                app.mData = app.mData(end - maxPoints : end);
            end
            
            plot(app.aPosition, app.mData);
            
            fclose(device);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            
            app.tPlotUpdater = timer('Name', 'PlotUpdater', ...
                            'Period', 0.01, ...
                            'TasksToExecute', inf, ... 
                            'ExecutionMode', 'fixedSpacing');
            
            app.tPlotUpdater.TimerFcn = @app.tPlotUpdaterCallback; 
        end

        % Value changed function: ddAxis
        function ddAxisValueChanged(app, event)
            value = app.ddAxis.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: btnStartStop
        function btnStartStopPushed(app, event)
            if strcmp(app.btnStartStop.Text, 'Start')
                app.btnStartStop.Text = 'Stop';
                start(app.tPlotUpdater);
            else
                app.btnStartStop.Text = 'Start';
                stop(app.tPlotUpdater);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.tPlotUpdater);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PortLabel = uilabel(app.LeftPanel);
            app.PortLabel.Text = 'Port';
            app.ddAvailableComs = uidropdown(app.LeftPanel);
            app.ddAvailableComs.Items = {'COM1', 'COM2', 'COM3'};
            app.ddAvailableComs.Value = 'COM1';
            app.AxisLabel = uilabel(app.LeftPanel);
            app.AxisLabel.Text = 'Axis';
            app.ddAxis = uidropdown(app.LeftPanel);
            app.ddAxis.Items = {'X', 'Y', 'Z'};
            app.ddAxis.ValueChangedFcn = createCallbackFcn(app, @ddAxisValueChanged, true);
            app.ddAxis.Value = 'X';
            app.btnStartStop = uibutton(app.LeftPanel, 'push');
            app.btnStartStop.ButtonPushedFcn = createCallbackFcn(app, @btnStartStopPushed, true);
            app.btnStartStop.Text = 'Start';
            app.RightPanel = uipanel(app.GridLayout);
            app.aPosition = uiaxes(app.RightPanel);
            title(app.aPosition, 'Position')
            xlabel(app.aPosition, 'X')
            ylabel(app.aPosition, 'Y')
            zlabel(app.aPosition, 'Z')
            app.aAccelerometer = uiaxes(app.RightPanel);
            title(app.aAccelerometer, {'Accelerometer'; ''})
            xlabel(app.aAccelerometer, 'X')
            ylabel(app.aAccelerometer, 'Y')
            zlabel(app.aAccelerometer, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end